classdef Ignition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainCalculationsTab             matlab.ui.container.Tab
        StopButton                      matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        SaveFileNameEditField_2         matlab.ui.control.EditField
        SaveFileNameEditField_2Label    matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        RunButton_2                     matlab.ui.control.Button
        GeneralInputsPanel              matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MachNumberPostDiffuserEditField_2  matlab.ui.control.NumericEditField
        MachNumberPostDiffuserEditField_2Label  matlab.ui.control.Label
        gminLabel                       matlab.ui.control.Label
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        mmLabel_3                       matlab.ui.control.Label
        degLabel                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        LsLabel                         matlab.ui.control.Label
        kmhrLabel                       matlab.ui.control.Label
        CLabel                          matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        JkgKLabel                       matlab.ui.control.Label
        NumberofPlotPointsEditField     matlab.ui.control.NumericEditField
        NumberofPlotPointsEditFieldLabel  matlab.ui.control.Label
        dotm_fuelEditField              matlab.ui.control.NumericEditField
        dotm_fuelEditFieldLabel         matlab.ui.control.Label
        TurbineDiameterEditField        matlab.ui.control.NumericEditField
        TurbineDiameterEditFieldLabel   matlab.ui.control.Label
        NozzleDiameterEditField         matlab.ui.control.NumericEditField
        NozzleDiameterEditFieldLabel    matlab.ui.control.Label
        FlameHolderHeightEditField_2    matlab.ui.control.NumericEditField
        FlameHolderHeightEditField_2Label  matlab.ui.control.Label
        FlameHolderHalfAngleEditField_2  matlab.ui.control.NumericEditField
        FlameHolderHalfAngleEditField_2Label  matlab.ui.control.Label
        StraightSectionLengthEditField_2  matlab.ui.control.NumericEditField
        StraightSectionLengthEditField_2Label  matlab.ui.control.Label
        DiffuserLengthEditField_2       matlab.ui.control.NumericEditField
        DiffuserLengthEditField_2Label  matlab.ui.control.Label
        TurbineBladeLengthEditField_3   matlab.ui.control.NumericEditField
        TurbineBladeLengthEditField_3Label  matlab.ui.control.Label
        dotm_inEditField                matlab.ui.control.NumericEditField
        dotm_inEditFieldLabel           matlab.ui.control.Label
        IgnitionTimeParameterEditField_2  matlab.ui.control.NumericEditField
        IgnitionTimeParameterEditField_2Label  matlab.ui.control.Label
        BlowoutVelocityParameterEditField_2  matlab.ui.control.NumericEditField
        BlowoutVelocityParameterEditField_2Label  matlab.ui.control.Label
        u_exitEditField                 matlab.ui.control.NumericEditField
        ExitVelocitykmhrLabel           matlab.ui.control.Label
        EGTEditField                    matlab.ui.control.NumericEditField
        EGTCLabel                       matlab.ui.control.Label
        rho_airEditField                matlab.ui.control.NumericEditField
        AirDensitykgm3Label             matlab.ui.control.Label
        SpecificGasConstantEditField    matlab.ui.control.NumericEditField
        SpecificGasConstantEditFieldLabel  matlab.ui.control.Label
        gammaEditField                  matlab.ui.control.NumericEditField
        GammaLabel                      matlab.ui.control.Label
        Image_4                         matlab.ui.control.Image
        CombustionAnalysisTab_2         matlab.ui.container.Tab
        EvaluateButton                  matlab.ui.control.Button
        CombustionResultsPanel          matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        kPaLabel                        matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        f_stoichiometricEditField       matlab.ui.control.NumericEditField
        f_stoichiometricLabel           matlab.ui.control.Label
        f_actualEditField               matlab.ui.control.NumericEditField
        f_actualLabel                   matlab.ui.control.Label
        PhiEditField                    matlab.ui.control.NumericEditField
        PhiLabel                        matlab.ui.control.Label
        AfterburnerPressureEditField    matlab.ui.control.NumericEditField
        AfterburnerPressureEditFieldLabel  matlab.ui.control.Label
        AfterburnerTemperatureEditField  matlab.ui.control.NumericEditField
        AfterburnerTemperatureEditFieldLabel  matlab.ui.control.Label
        CombustionParametersPanel       matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        Image_5                         matlab.ui.control.Image
        FinalResultsTab                 matlab.ui.container.Tab
        Image_3                         matlab.ui.control.Image
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UnitConversionsTab              matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        CalculateVolumeButton           matlab.ui.control.Button
        OutputVolumeEditField           matlab.ui.control.NumericEditField
        OutputVolumeEditFieldLabel      matlab.ui.control.Label
        InputVolumeEditField            matlab.ui.control.NumericEditField
        InputVolumeEditFieldLabel       matlab.ui.control.Label
        VolumeLabel                     matlab.ui.control.Label
        VolumeDropDown                  matlab.ui.control.DropDown
        MasstoVolumeFRLabel             matlab.ui.control.Label
        FlowSwapDropDown                matlab.ui.control.DropDown
        CalculateFlowSwapButton         matlab.ui.control.Button
        OutputFlowSwapEditField         matlab.ui.control.NumericEditField
        OutputFlowRateEditField_2Label  matlab.ui.control.Label
        MassorVolumeFRLabel             matlab.ui.control.Label
        VelocityLabel                   matlab.ui.control.Label
        PressureLabel                   matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        InputDensitykgm3EditField       matlab.ui.control.NumericEditField
        InputDensitykgm3EditFieldLabel  matlab.ui.control.Label
        InputFlowSwapEditField          matlab.ui.control.NumericEditField
        InputFlowRateEditField_2Label   matlab.ui.control.Label
        CalculateFlowRateButton         matlab.ui.control.Button
        CalculateVelocityButton         matlab.ui.control.Button
        CalculatePressureButton         matlab.ui.control.Button
        CalculateTempButton             matlab.ui.control.Button
        OutputFlowRateEditField         matlab.ui.control.NumericEditField
        OutputFlowRateEditFieldLabel    matlab.ui.control.Label
        InputFlowRateEditField          matlab.ui.control.NumericEditField
        InputFlowRateEditFieldLabel     matlab.ui.control.Label
        OutputVelocityEditField         matlab.ui.control.NumericEditField
        OutputVelocityEditFieldLabel    matlab.ui.control.Label
        InputVelocityEditField          matlab.ui.control.NumericEditField
        InputVelocityEditFieldLabel     matlab.ui.control.Label
        OutputPressureEditField         matlab.ui.control.NumericEditField
        OutputPressureEditFieldLabel    matlab.ui.control.Label
        InputPressureEditField          matlab.ui.control.NumericEditField
        InputPressureEditFieldLabel     matlab.ui.control.Label
        UnitConversionToolboxLabel      matlab.ui.control.Label
        FlowRateDropDown                matlab.ui.control.DropDown
        VelocityDropDown                matlab.ui.control.DropDown
        PressureDropDown                matlab.ui.control.DropDown
        OutputTemperatureEditField      matlab.ui.control.NumericEditField
        OutputTemperatureEditFieldLabel  matlab.ui.control.Label
        InputTemperatureEditField       matlab.ui.control.NumericEditField
        InputTemperatureEditFieldLabel  matlab.ui.control.Label
        TemperatureDropDown             matlab.ui.control.DropDown
        SettingsTab                     matlab.ui.container.Tab
        PlotColorPanel                  matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        NozzleDropDown                  matlab.ui.control.DropDown
        NozzleDropDownLabel             matlab.ui.control.Label
        FlameHolderDropDown             matlab.ui.control.DropDown
        FlameHolderDropDownLabel        matlab.ui.control.Label
        CombustionChamberDropDown       matlab.ui.control.DropDown
        CombustionChamberDropDownLabel  matlab.ui.control.Label
        StraightSectionDropDown         matlab.ui.control.DropDown
        StraightSectionDropDownLabel    matlab.ui.control.Label
        DiffuserDropDown                matlab.ui.control.DropDown
        DiffuserDropDownLabel           matlab.ui.control.Label
        EngineDropDown                  matlab.ui.control.DropDown
        EngineDropDownLabel             matlab.ui.control.Label
        ThemeDropDown                   matlab.ui.control.DropDown
        ThemeDropDownLabel              matlab.ui.control.Label
        Image_7                         matlab.ui.control.Image
    end

    
    properties (Access = private)
            %Reset and Stop button stuff
            %OriginalVals;
            %Stop = False;

            % Geometry & Design Variables
            d_turbine;  
            d_nozzle;           
            turbblade;          
            diff_length;      
            n_points;           
            D;                 
            theta;             
            l_diff;
            straight;
            rab;
            r_outer;
            testimage;

            
            % Thermodynamic & Flow Parameters
            Tc;                 
            BVP;                
            gamma;              
            R;                  
            rho_air;           
            T2;                
            u2;
            f_act;
            f_st;
            
            % Combustion and Mass Flow Variables
            mdot_in;            
            mdot_fuel;          
            mdot_air;          
            P3;
            Cp;
            T3;
            M3;
            Tpost;
            Ppost;
            Vlmax;
            phi;
            mixArray1;
            mixArray2;

            KonamiCode = {'uparrow','uparrow','downarrow','downarrow','leftarrow','rightarrow',...
                'leftarrow','rightarrow','b','a'};
            Usereasteregg = {};

            % Unit Conversions Properties
            SelectedTemperatureConversion % Stores selected temp conversion type
            TemperatureInputValue % Stores temp input number
            SelectedPressureConversion
            PressureInputValue
            SelectedVelocityConversion
            VelocityInputValue
            SelectedFlowRateConversion
            FlowRateInputValue
            SelectedFlowSwapConversion
            FlowSwapInputValue
            FlowSwapDensity
            SelectedVolumeConversion
            VolumeInputValue
    end

    methods (Access = private)
        
       function aas = aastar(app,M,gamma)
            aas = (1/M)*(2/(gamma+1)*(1+((gamma-1)/2)*M^2))^((gamma+1)/(2*(gamma-1)));
        end
        
        function TT0 = stagtemp(app,M,gamma)
            TT0 = (1+((gamma-1)/2)*M^2)^-1;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function startupFcn(app)
            app.UIFigure.Name = 'Ignition';
            data = load('testimage.mat');
            app.testimage = data.testimage;

            
            %sets initial values (suggestions) and saves values in case
            %things are reset (will implement later along with a reset
            %button)
            app.gammaEditField.Value = 1.4;
            app.SpecificGasConstantEditField.Value = 287;
            app.rho_airEditField.Value = 1.225;
            app.EGTEditField.Value = 800;
            app.u_exitEditField.Value = 1700;
            app.BlowoutVelocityParameterEditField_2.Value = 0.9516;
            app.IgnitionTimeParameterEditField_2.Value = 3.46e-4;
            app.dotm_inEditField.Value = 300;
            app.TurbineBladeLengthEditField_3.Value = 16e-3;
            app.DiffuserLengthEditField_2.Value = 0.120;
            app.StraightSectionLengthEditField_2.Value = 0.1;
            app.FlameHolderHalfAngleEditField_2.Value = 15;
            app.FlameHolderHeightEditField_2.Value = 0.25;
            app.NozzleDiameterEditField.Value = 51e-3;
            app.TurbineDiameterEditField.Value = 71e-3;
            app.dotm_fuelEditField.Value = 540;
            app.NumberofPlotPointsEditField.Value = 1000;

            % For a reset button. Not implemented yet
            % app.OriginialVals.in1 = app.gammaEditField.Value;
            % app.OriginialVals.in2 = app.SpecificGasConstantEditField.Value;
            % app.OriginialVals.in3 = app.rho_airEditField.Value;
            % app.OriginialVals.in4 = app.EGTEditField.Value;
            % app.OriginialVals.in5 = app.u_exitEditField.Value;
            % app.OriginialVals.in6 = app.BlowoutVelocityParameterEditField_2.Value;
            % app.OriginialVals.in7 = app.IgnitionTimeParameterEditField_2.Value;
            % app.OriginialVals.in8 = app.dotm_inEditField.Value;
            % app.OriginialVals.in9 = app.TurbineBladeLengthEditField_3.Value;
            % app.OriginialVals.in10 = app.DiffuserLengthEditField_2.Value;
            % app.OriginialVals.in11 = app.StraightSectionLengthEditField_2.Value;
            % app.OriginialVals.in12 = app.FlameHolderHalfAngleEditField_2.Value;
            % app.OriginialVals.in13 = app.FlameHolderHeightEditField_2.Value;
            % app.OriginialVals.in14 = app.NozzleDiameterEditField.Value;
            % app.OriginialVals.in15 = app.TurbineDiameterEditField.Value;
            % app.OriginialVals.in16 = app.dotm_fuelEditField.Value;
            % app.OriginialVals.in17 = app.NumberofPlotPointsEditField.Value;

          % Unit Conversions Startup Info:
            % Set initial values for input fields
            app.InputTemperatureEditField.Value = 25;  % Default temperature value
            app.InputPressureEditField.Value = 101325; % Default pressure in Pa
            app.InputFlowRateEditField.Value = 1;     % Default flow rate value
            app.InputVelocityEditField.Value = 10;    % Default velocity value
            app.InputDensitykgm3EditField.Value = 1.225; % default at sea-level air value 
            app.InputFlowSwapEditField.Value = 1; % Default flow swap value
            app.InputVolumeEditField.Value = 1; % Default Volume
        
            % Set the dropdown to a default value
            app.TemperatureDropDown.Value = 'Celsius -> Fahrenheit'; % Default temperature conversion
            app.PressureDropDown.Value = 'Pa -> kPA';               % Default pressure conversion
            app.VelocityDropDown.Value = 'm/s -> km/hr';            % Default velocity conversion
            app.FlowRateDropDown.Value = 'kg/s -> kg/min';          % Default flow rate conversion
            app.FlowSwapDropDown.Value = 'kg/s -> m^3/s';
            app.VolumeDropDown.Value = 'm^3 -> L';
        
            % Set usable variables to default values
            app.SelectedTemperatureConversion = app.TemperatureDropDown.Value;
            app.TemperatureInputValue = app.InputTemperatureEditField.Value;
        
            app.SelectedPressureConversion = app.PressureDropDown.Value;
            app.PressureInputValue = app.InputPressureEditField.Value;
        
            app.SelectedVelocityConversion = app.VelocityDropDown.Value;
            app.VelocityInputValue = app.InputVelocityEditField.Value;
        
            app.SelectedFlowRateConversion = app.FlowRateDropDown.Value;
            app.FlowRateInputValue = app.InputFlowRateEditField.Value;
        
            app.SelectedFlowSwapConversion = app.FlowSwapDropDown.Value;
            app.FlowSwapInputValue = app.InputFlowSwapEditField.Value;
            app.FlowSwapDensity = app.InputDensitykgm3EditField.Value;
        
            app.SelectedVolumeConversion = app.VolumeDropDown.Value;
            app.VolumeInputValue = app.InputVolumeEditField.Value;
            
            % Set default values for output fields
            app.OutputTemperatureEditField.Value = 0;  % Default output temperature value
            app.OutputPressureEditField.Value = 0;     % Default output pressure value
            app.OutputVelocityEditField.Value = 0;     % Default output velocity value
            app.OutputFlowRateEditField.Value = 0;     % Default ouput flow rate value
            % End Unit Conversions Startup Info
        end

        % Value changed function: gammaEditField
        function gammaEditFieldValueChanged(app, event)
            app.gamma = app.gammaEditField.Value;
            
        end

        % Value changed function: SpecificGasConstantEditField
        function SpecificGasConstantEditFieldValueChanged(app, event)
            app.R = app.SpecificGasConstantEditField.Value;
            
        end

        % Value changed function: rho_airEditField
        function rho_airEditFieldValueChanged(app, event)
            app.rho_air = app.rho_airEditField.Value;
            
        end

        % Value changed function: EGTEditField
        function EGTEditFieldValueChanged(app, event)
            app.T2 = app.EGTEditField.Value;
            
        end

        % Value changed function: u_exitEditField
        function u_exitEditFieldValueChanged(app, event)
            app.u2 = app.u_exitEditField.Value;
            
        end

        % Value changed function: BlowoutVelocityParameterEditField_2
        function BlowoutVelocityParameterEditField_2ValueChanged(app, event)
            app.BVP = app.BlowoutVelocityParameterEditField_2.Value;
            
        end

        % Value changed function: IgnitionTimeParameterEditField_2
        function IgnitionTimeParameterEditField_2ValueChanged(app, event)
            app.Tc = app.IgnitionTimeParameterEditField_2.Value;
            
        end

        % Value changed function: TurbineBladeLengthEditField_3
        function TurbineBladeLengthEditField_3ValueChanged(app, event)
            app.turbblade = app.TurbineBladeLengthEditField_3.Value;
        end

        % Value changed function: DiffuserLengthEditField_2
        function DiffuserLengthEditField_2ValueChanged(app, event)
            app.diff_length = app.DiffuserLengthEditField_2.Value;
            
        end

        % Value changed function: StraightSectionLengthEditField_2
        function StraightSectionLengthEditField_2ValueChanged(app, event)
            app.straight = app.StraightSectionLengthEditField_2.Value;
            
        end

        % Value changed function: FlameHolderHalfAngleEditField_2
        function FlameHolderHalfAngleEditField_2ValueChanged(app, event)
            app.theta = app.FlameHolderHalfAngleEditField_2.Value;
            
        end

        % Value changed function: FlameHolderHeightEditField_2
        function FlameHolderHeightEditField_2ValueChanged(app, event)
            app.D = app.FlameHolderHeightEditField_2.Value;
            
        end

        % Value changed function: NumberofPlotPointsEditField
        function NumberofPlotPointsEditFieldValueChanged(app, event)
            app.n_points = app.NumberofPlotPointsEditField.Value;
            
        end

        % Value changed function: SaveFileNameEditField_2
        function SaveFileNameEditField_2ValueChanged(app, event)
            app.savename = app.SaveFileNameEditField_2.Value;
            
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            savename = 'diffuser_geometry.csv';
            csvwrite(savename, data);
            %Would like to also tell the user where the file has been saved
            %to / let them go into a file browser at some point
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            %unimplemented stop button
            %app.Stop = false;
            %while app.Stop == false
                
                %lets the user know things are happening
                app.RunButton_2.Text = 'Processing...';
                drawnow;
                pause(0.25);
                %For some reason, the code doesn't want to run unless I
                %redefine everything (all the vars) again in the run button
                %code. Even though each edit field has it's own command, this
                %thing still won't work. So they'll be redesigned below

                app.gamma = app.gammaEditField.Value;
                app.R = app.SpecificGasConstantEditField.Value;
                app.rho_air = app.rho_airEditField.Value;
                app.T2 = app.EGTEditField.Value;
                app.u2 = app.u_exitEditField.Value;
                app.BVP = app.BlowoutVelocityParameterEditField_2.Value;
                app.Tc = app.IgnitionTimeParameterEditField_2.Value;
                app.turbblade = app.TurbineBladeLengthEditField_3.Value;
                app.diff_length = app.DiffuserLengthEditField_2.Value;
                app.straight = app.StraightSectionLengthEditField_2.Value;
                app.theta = app.FlameHolderHalfAngleEditField_2.Value;
                app.D = app.FlameHolderHeightEditField_2.Value;
                app.n_points = app.NumberofPlotPointsEditField.Value;
                app.d_nozzle = app.NozzleDiameterEditField.Value;
                app.d_turbine = app.TurbineDiameterEditField.Value;
                app.mdot_in = app.dotm_inEditField.Value;
                app.mdot_fuel = app.dotm_fuelEditField.Value;

                pie = double(pi);
                A1 = (pie*app.d_turbine^2)/4;
                A2 = (pie*app.d_nozzle^2)/4;

                %Convert from C to K
                app.T2 = app.T2 + 273.15;

                %Convert from km/h to m/s
                app.u2 = app.u2*1000*(1/3600);

                M2 = app.u2/sqrt(app.gamma*app.R*app.T2);

                %Isentropic Area Relations
                a2astar = app.aastar(M2,app.gamma);
                Astar = A2/a2astar;

                %Finds M1 using area relations. Outputs a vector but answer takes only
                %real, subsonic answers (see M1 = M1(1))
                syms M1
                a1astar = app.aastar(M1,app.gamma);
                M1 = vpasolve(A1/Astar == a1astar,M1);
                M1 = M1(1);

                %Find the stagnation temperature for the nozzle and then finds temp at
                %section '1'
                T2T0 = app.stagtemp(M2,app.gamma);

                T0 = app.T2/T2T0;

                T1T0 = app.stagtemp(M1,app.gamma);
                T1 = T1T0*T0;

                %calculate velocity coming out of the turbine => goes into the afterburner
                %diffuser
                u1 = M1*sqrt(app.gamma*app.R*T1);


                %Need to make an initial assumption to complete the diffuser/ flame holder
                %sections. We will take diameter of the afterburner to be 91mm. Will be set
                %in relation to the turbine to make it easier to recalculate (less
                %variables to reinput). Also need to pass in an ignition time parameter.
                %Initial guess was collected from Table 2 in the AB report we're following.
                %Will likely be adusted later.
                
                %Diffuser Geometry
                rab = app.d_turbine/2 + 0.01;
                
                %This needs to be added as an edit field
                app.l_diff = 0.025; %r_diff/tand(theta);%not sure what to
                %do with this. it's being weird and im not sure which one
                %is correct
                app.Vlmax = app.BVP*(rab/app.Tc); %may need to be set by user??

                app.Cp = (app.gamma*app.R)/(app.gamma-1);
                app.T3 = T1 + (u1^2/(2*app.Cp)) - (app.Vlmax^2/(2*app.Cp));

                app.M3 = app.Vlmax/(sqrt(app.gamma*app.R*app.T3));
                %generally speaking, the M <= 0.4. Not sure I want to run a
                %check atm. Might implement later

                % Define input parameters
                r1_inner = app.turbblade;  % Inner inlet radius (meters)
                r3_inner = 0; % Inner outlet radius (meters)

                r1_outer = app.d_turbine/2;  % Outer inlet radius (meters)
                r3_outer = rab; % Outer outlet radius (meters)

                % Generate x-coordinates (axial positions)
                x = linspace(0, app.diff_length, app.n_points);

                % Compute inner and outer radius values assuming linear increase
                r_inner = linspace(r1_inner, r3_inner, app.n_points);
                r_outer = linspace(r1_outer, r3_outer, app.n_points);

                % Compute corresponding cross-sectional areas
                %A_inner = pi32 * r_inner.^2; % Inner area (hollow region)
                %A_outer = pi32 * r_outer.^2; % Outer total area

                %A_flow = A_outer - A_inner; % Flow area (actual open space)
                %%might be unused and leftover from earlier versions of the
                %%code. Could be used later to find the local values for a
                %%plot (temp, pres, mach, etc)

                % Combine into a matrix for CSV output
                %data = [x' r_inner' r_outer' A_inner' A_outer' A_flow'];

                app.D = app.D*0.0254;%Converts from in to m
                %r_diff = app.D/2; %Unused???

                %Nozzle Calculations
                %for now, using Tpost = 2600K Ppost = 220kPa
                app.Tpost = 2600;
                app.Ppost = 220000;
                Mpost = app.Vlmax/(sqrt(app.gamma*app.R*app.Tpost));
                A_comb_chamber = pi*(r3_outer(end)^2);
                %For converging nozzle, assume Mexit = 1 (ideal case)
                Mexit = 1;
                Aexit = ((Mpost*A_comb_chamber)/Mexit)*sqrt(((1+((app.gamma-1)/2)*Mexit^2)/(1+((app.gamma-1)/2)*Mpost^2))^((app.gamma+1)/(app.gamma-1)));
                rexit = sqrt(Aexit/pi);


                % Plot geometry
                %plot color will be fully implemented later
                plotcolord = 'r';
                plotcolore = 'm';
                plotcolorf = 'g';

                cla(app.UIAxes2);
                hold(app.UIAxes2, 'on');

                %Diffuser
                plot(app.UIAxes2,[-0.2,0.5], [0,0], 'k--', 'LineWidth', 1.5); % Horizontal x-axis as white dashed line
                plot(app.UIAxes2,x, r_outer, 'Color', plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, -r_outer, 'Color',plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, r_inner, 'Color',plotcolord, 'LineWidth', 2);
                plot(app.UIAxes2,x, -r_inner, 'Color',plotcolord, 'LineWidth', 2);


                %Straight expanding section
                plot(app.UIAxes2,[app.diff_length, app.diff_length + app.straight], [r3_outer, r3_outer], 'Color', plotcolore, 'LineWidth', 2);
                plot(app.UIAxes2,[app.diff_length, app.diff_length + app.straight], [-r3_outer, -r3_outer], 'Color', plotcolore, 'LineWidth', 2);


                %Flame holder
                flame_holder_start = [0.22, 0.025];

                x_v1 = flame_holder_start(1) + app.l_diff * cosd(app.theta);
                y_v1 = flame_holder_start(2) + app.l_diff * sind(app.theta);
                x_v2 = flame_holder_start(1) + app.l_diff * cosd(app.theta);
                y_v2 = flame_holder_start(2) - app.l_diff * sind(app.theta);

                plot(app.UIAxes2,[flame_holder_start(1), x_v1], [flame_holder_start(2), y_v1], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v2], [flame_holder_start(2), y_v2], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v1], [-flame_holder_start(2), -y_v1], 'Color', plotcolorf, 'LineWidth', 2);
                plot(app.UIAxes2,[flame_holder_start(1), x_v2], [-flame_holder_start(2), -y_v2], 'Color', plotcolorf, 'LineWidth', 2);


                %Engine
                % Define the coordinates for the rectangle
                x_coords = [-0.172, 0];  % x-coordinates for the left and right sides
                y_coords_top = [0.055, 0.055];  % y-coordinates for the top side
                y_coords_bottom = [-0.055, -0.055];  % y-coordinates for the bottom side

                plot(app.UIAxes2, x_coords, y_coords_top, 'k','LineWidth',2);  % Top edge
                plot(app.UIAxes2, x_coords, y_coords_bottom, 'k','LineWidth',2);  % Bottom edge
                plot(app.UIAxes2, [-0.172, -0.172], [-0.055, 0.055], 'k','LineWidth',2);  % Left edge
                plot(app.UIAxes2, [0, 0], [-0.055, 0.055], 'k','LineWidth',2);  % Right edge

                %Combustion Chamber
                x_combustion = linspace(0.22, 0.22 + (app.straight * 1.5), 100); % Define x range
                y_combustion = ones(size(x_combustion)) * r3_outer; % Constant y-value

                plot(app.UIAxes2,x_combustion, y_combustion, 'b','LineWidth',2); % Plot the combustion chamber section
                plot(app.UIAxes2,x_combustion, -y_combustion, 'b','LineWidth',2); % Plot the combustion chamber section


                %Nozzle
                %Should allow the user to choose between C and CND. For the
                %C nozzle, assume linear taper down to final area. For the
                %CND nozzle, also allow the user to choose between linear
                %or bell shaped nozzle. For now only a single C nozzle is
                %implemented
                %Nozzle
                x_start = 0.37; % Define starting x position
                x_nozzle = linspace(x_start, x_start + 0.1, 50); % Nozzle length
                y_nozzle = linspace(r_outer(end), rexit, 50);  % Linear taper from initial to final radius
                plot(app.UIAxes2,x_nozzle, -y_nozzle, 'c', 'LineWidth', 2);
                plot(app.UIAxes2,x_nozzle, y_nozzle, 'c', 'LineWidth', 2);


                grid(app.UIAxes2,'on');
                xlabel(app.UIAxes2,'Axial Position (m)');
                ylabel(app.UIAxes2,'Radius (m)');
                title(app.UIAxes2,'Afterburner Geometry');

                %legend
                leg_diff = plot(app.UIAxes2,NaN,NaN,'Color',plotcolord,'LineWidth',2);
                leg_str = plot(app.UIAxes2,NaN,NaN,'Color',plotcolore,'LineWidth',2);
                leg_fl = plot(app.UIAxes2,NaN,NaN,'Color',plotcolorf,'LineWidth',2);
                leg_en = plot(app.UIAxes2,NaN,NaN,'Color','k','LineWidth',2);
                leg_comb = plot(app.UIAxes2,NaN,NaN,'Color','b','LineWidth',2);
                leg_noz = plot(app.UIAxes2,NaN,NaN,'Color','c','LineWidth',2);
                legend(app.UIAxes2,[leg_en,leg_diff,leg_str,leg_fl,leg_comb,leg_noz],'Engine','Diffuser','Straight Section','Flame Holder','Combustion Chamber','Nozzle','Location', 'northwest');

                hold(app.UIAxes2,'off');
                app.RunButton_2.Text = 'Completed';
            %end

        end

        % Value changed function: NozzleDiameterEditField
        function NozzleDiameterEditFieldValueChanged(app, event)
            app.d_nozzle = app.NozzleDiameterEditField.Value;
            
        end

        % Value changed function: TurbineDiameterEditField
        function TurbineDiameterEditFieldValueChanged(app, event)
             app.d_turbine = app.TurbineDiameterEditField.Value;
            
        end

        % Value changed function: dotm_inEditField
        function dotm_inEditFieldValueChanged(app, event)
            app.mdot_in = app.dotm_inEditField.Value;
            
        end

        % Value changed function: dotm_fuelEditField
        function dotm_fuelEditFieldValueChanged(app, event)
            app.mdot_fuel = app.dotm_fuelEditField.Value;
            
        end

        % Button pushed function: EvaluateButton
        function EvaluateButtonPushed(app, event)
            app.EvaluateButton.Text = 'Processing...';
            drawnow;
            pause(0.25);
            
            app.mdot_in = app.mdot_in*10^-3; %m^3/s
            app.mdot_fuel = app.mdot_fuel*(1/1000)*(1/60); %kg/s
            mdot_total = app.rho_air*app.mdot_in;
            app.mdot_air = mdot_total - app.mdot_fuel;
            
            app.f_act = app.mdot_fuel/app.mdot_air;

            % Number passing into the toolbox needs to be an actual value. 
            % Comes out as a weird symbolic thing from earlier calculations
            app.T3 = eval(app.T3);
            
            MW_carbon = 12.011;
            MW_oxygen = 15.999;
            MW_nitrogen = 14.007;
            MW_hydrogen = 1.0078;
            MW_fuel = 12*MW_carbon + 24*MW_hydrogen;
            MW_oxidizer = 2*MW_oxygen + 3.74*2*MW_nitrogen; %Not sure if this is correct (ie supposed to not include the nitrogen)
            app.f_st = MW_fuel/(18*MW_oxidizer);
            
            app.phi = app.f_act/app.f_st;
            app.PhiEditField.Value = app.phi;
            
            %mol_air_new = 18/phi;  %Unused for now? Might want to display
            %to user

            % Import packages
            import combustiontoolbox.databases.NasaDatabase
            import combustiontoolbox.core.*
            import combustiontoolbox.equilibrium.*
            import combustiontoolbox.common.Units

            app.P3 = 2.179;%atm %Pressure needs to be in bar to pass into the combustion toolbox
            app.P3 = app.P3*1.01325;
            app.P3 = Units.convert(2.179,'atm','bar');
            
            DB = NasaDatabase();
            
            system = ChemicalSystem(DB);
            mix = Mixture(system);
            
            set(mix,{'Jet_AbLb'},'fuel', 2.115);
            set(mix,{'O2','N2'},'oxidizer',[31.02,315.969]/31.02); %needs to normalize around 1 mol of O2 (Alberto said this)
            app.mixArray1 = setProperties(mix,'temperature',app.T3,'pressure',app.P3,'equivalenceRatio',1); % 1=> stoiciometric combustion
            
            app.mixArray2 = app.mixArray1.copy(); %For the products
            
            solver = EquilibriumSolver('problemType','HP'); %adiabatic and constant P
            
            combustion = solver.solveArray(app.mixArray2);
            
            app.AfterburnerTemperatureEditField.Value = combustion.T;
            app.AfterburnerPressureEditField.Value = combustion.p;
            app.AfterburnerPressureEditField.Value = Units.convert(app.AfterburnerPressureEditField.Value, 'bar','Pa');
            app.AfterburnerPressureEditField.Value = app.AfterburnerPressureEditField.Value*10^-3;
            app.EvaluateButton.Text = 'Completed';
            
        end

        % Value changed function: AfterburnerTemperatureEditField
        function AfterburnerTemperatureEditFieldValueChanged(app, event)
            
        end

        % Value changed function: AfterburnerPressureEditField
        function AfterburnerPressureEditFieldValueChanged(app, event)

        end

        % Value changed function: PhiEditField
        function PhiEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function Element1DropDownValueChanged(app, event)
            selectedValue = app.Dropdown.Value; % Get the selected value from the dropdown

            % Set the corresponding molecular weight (MW) based on the selection
            switch selectedValue
                case 'Carbon'
                    app.MW1EditField.Value = 12.011; % MW for Carbon
                case 'Oxygen'
                    app.MW1EditField.Value = 15.999; % MW for Oxygen
                case 'Nitrogen'
                    app.MW1EditField.Value = 14.007; % MW for Nitrogen
                case 'Hydrogen'
                    app.MW1EditField.Value = 1.008;  % MW for Hydrogen
                otherwise
                    app.MW1EditField.Value = 0; % Default value (optional)
            end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % app.gammaEditField.Value = app.OriginialVals.in1;
            % app.SpecificGasConstantEditField.Value = app.OriginialVals.in2;
            % app.rho_airEditField.Value = app.OriginialVals.in3;
            % app.EGTEditField.Value = app.OriginialVals.in4;
            % app.u_exitEditField.Value = app.OriginialVals.in5;
            % app.BlowoutVelocityParameterEditField_2.Value = app.OriginialVals.in6;
            % app.IgnitionTimeParameterEditField_2.Value = app.OriginialVals.in7;
            % app.dotm_inEditField.Value = app.OriginialVals.in8;
            % app.TurbineBladeLengthEditField_3.Value = app.OriginialVals.in9;
            % app.DiffuserLengthEditField_2.Value = app.OriginialVals.in10;
            % app.StraightSectionLengthEditField_2.Value = app.OriginialVals.in11l;
            % app.FlameHolderHalfAngleEditField_2.Value = app.OriginialVals.in12;
            % app.FlameHolderHeightEditField_2.Value = app.OriginialVals.in13;
            % app.NozzleDiameterEditField.Value = app.OriginialVals.in14;
            % app.TurbineDiameterEditField.Value = app.OriginialVals.in15;
            % app.dotm_fuelEditField.Value = app.OriginialVals.in16;
            % app.NumberofPlotPointsEditField.Value = app.OriginialVals.in17;

            %need to also make the button press change the text on the
            %start button
            cla(app.UIAxes2);
            legend(app.UIAxes2,'off');
            app.RunButton_2.Text = 'Start';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            %app.Stop = true;
        end

        % Value changed function: f_actualEditField
        function f_actualEditFieldValueChanged(app, event)
            app.f_actualEditField.Value = app.f_act;
            
        end

        % Value changed function: f_stoichiometricEditField
        function f_stoichiometricEditFieldValueChanged(app, event)
            app.f_stoichiometricEditField.Value = app.f_st;
            
        end

        % Value changed function: ThemeDropDown
        function ThemeDropDownValueChanged(app, event)
            %Not implemented yet
            
        end

        % Value changed function: EngineDropDown
        function EngineDropDownValueChanged(app, event)
            
            
        end

        % Value changed function: MachNumberPostDiffuserEditField_2
        function MachNumberPostDiffuserEditField_2ValueChanged(app, event)

            
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            app.Usereasteregg{end+1} = key;

            if length(app.Usereasteregg) > length(app.KonamiCode) ||...
                    ~isequal(app.Usereasteregg,app.KonamiCode(1:length(app.Usereasteregg)))
                app.Usereasteregg = {};
            elseif length(app.Usereasteregg) == length(app.KonamiCode)
                fig = figure('WindowState','maximized','MenuBar','none','Name','','NumberTitle','off');;
                imshow('Jank.jpg','Border','tight');

                [y,Fs] = audioread('pipe.mp3');
                sound(y,Fs);

                app.Usereasteregg = {};
            end


            % For Unit Conversions Tab
            % Check if the Enter key is pressed
    if strcmp(key, 'return') || strcmp(key, 'enter')
        % Run the calculation functions for all 4 calculation buttons
        
        % Call the function to calculate temperature
        CalculateTempButtonPushed(app, event);
        
        % Call the function to calculate pressure
        CalculatePressureButtonPushed(app, event);
        
        % Call the function to calculate velocity
        CalculateVelocityButtonPushed(app, event);
        
        % Call the function to calculate flow rate
        CalculateFlowRateButtonPushed(app, event);

        % Call the function to calculate flow swap
        CalculateFlowSwapButtonPushed(app, event);

        % Call the function to calculate volume
        CalculateVolumeButtonPushed(app, event)
    end
    
        end

        % Value changed function: TemperatureDropDown
        function TemperatureDropDownValueChanged(app, event)
            app.SelectedTemperatureConversion = app.TemperatureDropDown.Value;
            
        end

        % Value changed function: InputTemperatureEditField
        function InputTemperatureEditFieldValueChanged(app, event)
            app.TemperatureInputValue = app.InputTemperatureEditField.Value;
            
        end

        % Button pushed function: CalculateTempButton
        function CalculateTempButtonPushed(app, event)
             % Check if an input value exists
    
    % Get stored input and conversion type
    inputValue = app.TemperatureInputValue;
    conversionType = app.SelectedTemperatureConversion;
    
    disp(class(app.SelectedTemperatureConversion));  % Check the type of the value
    disp(app.SelectedTemperatureConversion);  % Check the value itself

    % Perform conversion
    switch conversionType
        case 'Kelvin -> Celsius'
            result = inputValue - 273.15;
        case 'Celsius -> Kelvin'
            result = inputValue + 273.15;
        case 'Celsius -> Fahrenheit'
            result = (inputValue * 9/5) + 32;
        case 'Fahrenheit -> Celsius'
            result = (inputValue - 32) * 5/9;
        case 'Kelvin -> Fahrenheit'
            result = (inputValue - 273.15) * 9/5 + 32;
        case 'Fahrenheit -> Kelvin'
            result = (inputValue - 32) * 5/9 + 273.15;
        otherwise
            result = NaN;
    end
    
    % Display result in output field
    app.OutputTemperatureEditField.Value = result;
        end

        % Value changed function: PressureDropDown
        function PressureDropDownValueChanged(app, event)
            app.SelectedPressureConversion = app.PressureDropDown.Value;
            
        end

        % Value changed function: InputPressureEditField
        function InputPressureEditFieldValueChanged(app, event)
            app.PressureInputValue = app.InputPressureEditField.Value;
            
        end

        % Value changed function: VelocityDropDown
        function VelocityDropDownValueChanged(app, event)
            app.SelectedVelocityConversion = app.VelocityDropDown.Value;
            
        end

        % Value changed function: InputVelocityEditField
        function InputVelocityEditFieldValueChanged(app, event)
            app.VelocityInputValue = app.InputVelocityEditField.Value;
            
        end

        % Value changed function: FlowRateDropDown
        function FlowRateDropDownValueChanged(app, event)
            app.SelectedFlowRateConversion = app.FlowRateDropDown.Value;
            
        end

        % Value changed function: InputFlowRateEditField
        function InputFlowRateEditFieldValueChanged(app, event)
            app.FlowRateInputValue = app.InputFlowRateEditField.Value;
            
        end

        % Button pushed function: CalculatePressureButton
        function CalculatePressureButtonPushed(app, event)
            % Get the input pressure value and selected conversion options
    inputPressure = app.InputPressureEditField.Value;
    selectedPressureConversion = app.PressureDropDown.Value;

    % Initialize the result variable
    convertedPressure = 0;

    % Use a switch-case statement for pressure conversion
    switch selectedPressureConversion
        case 'Pa -> kPA'
            % Convert from Pascals to kilopascals
            convertedPressure = inputPressure / 1000;
        case 'kPa -> Pa'
            % Convert from kilopascals to Pascals
            convertedPressure = inputPressure * 1000;
        case 'kPa -> bars'
            % Convert from kilopascals to bars
            convertedPressure = inputPressure / 100;
        case 'kPa -> atm'
            % Convert from kilopascals to atmospheres
            convertedPressure = inputPressure / 101.325;
        case 'atm -> kPa'
            % Convert from atmospheres to kilopascals
            convertedPressure = inputPressure * 101.325;
        case 'atm -> bars'
            % Convert from atmospheres to bars
            convertedPressure = inputPressure * 1.01325;
        case 'bars -> kPa'
            % Convert from bars to kilopascals
            convertedPressure = inputPressure * 100;
        case 'bars -> atm'
            % Convert from bars to atmospheres
            convertedPressure = inputPressure / 1.01325;
        otherwise
            % Handle invalid or unexpected cases (if necessary)
            convertedPressure = NaN;
            disp('Invalid pressure conversion');
    end

    % Display the result in the Output Pressure Edit Field
    app.OutputPressureEditField.Value = convertedPressure;
        end

        % Button pushed function: CalculateVelocityButton
        function CalculateVelocityButtonPushed(app, event)
             % Check if an input value exists

    % Get stored input and conversion type
    inputValue = app.VelocityInputValue;
    conversionType = app.SelectedVelocityConversion;
    
    disp(class(app.SelectedVelocityConversion));  % Check the type of the value
    disp(app.SelectedVelocityConversion);  % Check the value itself

    % Perform conversion
    switch conversionType
        case 'm/s -> km/hr'
            result = inputValue * 3.6;
        case 'm/s -> mph'
            result = inputValue * 2.23694;
        case 'km/hr -> m/s'
            result = inputValue / 3.6;
        case 'km/hr -> mph'
            result = inputValue / 1.60934;
        case 'mph -> m/s'
            result = inputValue / 2.23694;
        case 'mph -> km/hr'
            result = inputValue * 1.60934;
        otherwise
            result = NaN;
    end
    
    % Display result in output field
    app.OutputVelocityEditField.Value = result;
        end

        % Button pushed function: CalculateFlowRateButton
        function CalculateFlowRateButtonPushed(app, event)
            % Get stored input and conversion type
    inputValue = app.FlowRateInputValue;
    conversionType = app.SelectedFlowRateConversion;
    
    % Perform conversion
    switch conversionType
        case 'kg/s -> kg/min'
            result = inputValue * 60;
        case 'kg/s -> g/min'
            result = inputValue * 60 * 1000;
        case 'kg/min -> kg/s'
            result = inputValue / 60;
        case 'kg/min -> g/min'
            result = inputValue * 1000;
        case 'g/min -> kg/s'
            result = inputValue / (60 * 1000);
        case 'g/min -> kg/min'
            result = inputValue / 1000;
        case 'm^3/s -> m^3/min'
            result = inputValue * 60;
        case 'm^3/s -> L/s'
            result = inputValue * 1000;
        case 'm^3/min -> m^3/s'
            result = inputValue / 60;
        case 'm^3/min -> L/s'
            result = (inputValue * 1000) / 60;
        case 'L/s -> m^3/s'
            result = inputValue / 1000;
        case 'L/s -> m^3/min'
            result = (inputValue / 1000) * 60;
        otherwise
            result = NaN;
    end
    
    % Display result in output field
    app.OutputFlowRateEditField.Value = result;
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            
        end

        % Value changed function: FlowSwapDropDown
        function FlowSwapDropDownValueChanged(app, event)
            app.SelectedFlowSwapConversion = app.FlowSwapDropDown.Value;
            
        end

        % Value changed function: InputFlowSwapEditField
        function InputFlowSwapEditFieldValueChanged(app, event)
            app.FlowSwapInputValue = app.InputFlowSwapEditField.Value;
            
        end

        % Value changed function: InputDensitykgm3EditField
        function InputDensitykgm3EditFieldValueChanged(app, event)
            app.FlowSwapDensity = app.InputDensitykgm3EditField.Value;
            
        end

        % Button pushed function: CalculateFlowSwapButton
        function CalculateFlowSwapButtonPushed(app, event)
            % Get stored input values
    inputValue = app.FlowSwapInputValue; % Input flow rate
    density = app.FlowSwapDensity;       % Input density
    conversionType = app.SelectedFlowSwapConversion; % Conversion type selection

    disp(class(app.SelectedFlowSwapConversion));  % Check the type of the value
    disp(app.SelectedFlowSwapConversion);  % Check the value itself

    % Ensure density is not zero to prevent division errors
    if density == 0
        result = NaN;
    else
        % Perform conversion
        switch conversionType
            case 'kg/s -> m^3/s'
                result = inputValue / density; % m³/s = kg/s ÷ kg/m³
            case 'm^3/s -> kg/s'
                result = inputValue * density; % kg/s = m³/s × kg/m³
            otherwise
                result = NaN;
        end
    end
    
    % Display result in output field
    app.OutputFlowSwapEditField.Value = result;
        end

        % Value changed function: VolumeDropDown
        function VolumeDropDownValueChanged(app, event)
            app.SelectedVolumeConversion = app.VolumeDropDown.Value;
            
        end

        % Value changed function: InputVolumeEditField
        function InputVolumeEditFieldValueChanged(app, event)
            app.VolumeInputValue = app.InputVolumeEditField.Value;
            
        end

        % Button pushed function: CalculateVolumeButton
        function CalculateVolumeButtonPushed(app, event)
             % Check if an input value exists
    if isempty(app.VolumeInputValue) || isnan(app.VolumeInputValue)
        app.OutputVolumeEditField.Value = 'Invalid Input';
        return;
    end
    
    % Get stored input and conversion type
    inputValue = app.VolumeInputValue;
    conversionType = app.SelectedVolumeConversion;
    
    % Perform conversion
    switch conversionType
        case 'm^3 -> L'
            result = inputValue * 1000;  % 1 m^3 = 1000 L
        case 'm^3 -> cm^3'
            result = inputValue * 1e6;  % 1 m^3 = 1,000,000 cm^3
        case 'cm^3 -> L'
            result = inputValue / 1000;  % 1 cm^3 = 0.001 L
        case 'cm^3 -> m^3'
            result = inputValue / 1e6;  % 1 cm^3 = 1e-6 m^3
        case 'L -> m^3'
            result = inputValue / 1000;  % 1 L = 0.001 m^3
        case 'L -> cm^3'
            result = inputValue * 1000;  % 1 L = 1000 cm^3
        otherwise
            result = NaN;
    end
    
    % Display result in output field
    app.OutputVolumeEditField.Value = result;
        end

        % Button down function: UnitConversionsTab
        function UnitConversionsTabButtonDown(app, event)
            % Unit Conversions Startup Info:
            % Set initial values for input fields
            app.InputTemperatureEditField.Value = 25;  % Default temperature value
            app.InputPressureEditField.Value = 101325; % Default pressure in Pa
            app.InputFlowRateEditField.Value = 1;     % Default flow rate value
            app.InputVelocityEditField.Value = 10;    % Default velocity value
            app.InputDensitykgm3EditField.Value = 1.225; % default at sea-level air value 
            app.InputFlowSwapEditField.Value = 1; % Default flow swap value
            app.InputVolumeEditField.Value = 1; % Default Volume
        
            % Set the dropdown to a default value
            app.TemperatureDropDown.Value = 'Celsius -> Fahrenheit'; % Default temperature conversion
            app.PressureDropDown.Value = 'Pa -> kPA';               % Default pressure conversion
            app.VelocityDropDown.Value = 'm/s -> km/hr';            % Default velocity conversion
            app.FlowRateDropDown.Value = 'kg/s -> kg/min';          % Default flow rate conversion
            app.FlowSwapDropDown.Value = 'kg/s -> m^3/s';
            app.VolumeDropDown.Value = 'm^3 -> L';
        
            % Set usable variables to default values
            app.SelectedTemperatureConversion = app.TemperatureDropDown.Value;
            app.TemperatureInputValue = app.InputTemperatureEditField.Value;
        
            app.SelectedPressureConversion = app.PressureDropDown.Value;
            app.PressureInputValue = app.InputPressureEditField.Value;
        
            app.SelectedVelocityConversion = app.VelocityDropDown.Value;
            app.VelocityInputValue = app.InputVelocityEditField.Value;
        
            app.SelectedFlowRateConversion = app.FlowRateDropDown.Value;
            app.FlowRateInputValue = app.InputFlowRateEditField.Value;
        
            app.SelectedFlowSwapConversion = app.FlowSwapDropDown.Value;
            app.FlowSwapInputValue = app.InputFlowSwapEditField.Value;
            app.FlowSwapDensity = app.InputDensitykgm3EditField.Value;
        
            app.SelectedVolumeConversion = app.VolumeDropDown.Value;
            app.VolumeInputValue = app.InputVolumeEditField.Value;
            
            % Set default values for output fields
            app.OutputTemperatureEditField.Value = 0;  % Default output temperature value
            app.OutputPressureEditField.Value = 0;     % Default output pressure value
            app.OutputVelocityEditField.Value = 0;     % Default output velocity value
            app.OutputFlowRateEditField.Value = 0;     % Default ouput flow rate value
            % End Unit Conversions Startup Info
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainCalculationsTab = uitab(app.TabGroup);
            app.MainCalculationsTab.Title = 'Main Calculations';
            app.Image_4 = uiimage(app.MainCalculationsTab);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.GeneralInputsPanel = uipanel(app.MainCalculationsTab);
            app.GeneralInputsPanel.Title = 'General Inputs';
            app.GridLayout2 = uigridlayout(app.GeneralInputsPanel);
            app.GammaLabel = uilabel(app.GridLayout2);
            app.GammaLabel.Interpreter = 'latex';
            app.GammaLabel.Text = '$\gamma$';
            app.gammaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.gammaEditField.ValueChangedFcn = createCallbackFcn(app, @gammaEditFieldValueChanged, true);
            app.SpecificGasConstantEditFieldLabel = uilabel(app.GridLayout2);
            app.SpecificGasConstantEditFieldLabel.Text = 'Specific Gas Constant';
            app.SpecificGasConstantEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SpecificGasConstantEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificGasConstantEditFieldValueChanged, true);
            app.AirDensitykgm3Label = uilabel(app.GridLayout2);
            app.AirDensitykgm3Label.Interpreter = 'latex';
            app.AirDensitykgm3Label.Text = '$\rho_{air}$';
            app.rho_airEditField = uieditfield(app.GridLayout2, 'numeric');
            app.rho_airEditField.ValueChangedFcn = createCallbackFcn(app, @rho_airEditFieldValueChanged, true);
            app.EGTCLabel = uilabel(app.GridLayout2);
            app.EGTCLabel.Text = 'EGT';
            app.EGTEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EGTEditField.ValueChangedFcn = createCallbackFcn(app, @EGTEditFieldValueChanged, true);
            app.ExitVelocitykmhrLabel = uilabel(app.GridLayout2);
            app.ExitVelocitykmhrLabel.Interpreter = 'latex';
            app.ExitVelocitykmhrLabel.Text = '$u_{exit}$';
            app.u_exitEditField = uieditfield(app.GridLayout2, 'numeric');
            app.u_exitEditField.ValueChangedFcn = createCallbackFcn(app, @u_exitEditFieldValueChanged, true);
            app.BlowoutVelocityParameterEditField_2Label = uilabel(app.GridLayout2);
            app.BlowoutVelocityParameterEditField_2Label.Text = 'Blowout Velocity Parameter';
            app.BlowoutVelocityParameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.BlowoutVelocityParameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @BlowoutVelocityParameterEditField_2ValueChanged, true);
            app.IgnitionTimeParameterEditField_2Label = uilabel(app.GridLayout2);
            app.IgnitionTimeParameterEditField_2Label.Text = 'Ignition Time Parameter';
            app.IgnitionTimeParameterEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.IgnitionTimeParameterEditField_2.ValueChangedFcn = createCallbackFcn(app, @IgnitionTimeParameterEditField_2ValueChanged, true);
            app.dotm_inEditFieldLabel = uilabel(app.GridLayout2);
            app.dotm_inEditFieldLabel.Interpreter = 'latex';
            app.dotm_inEditFieldLabel.Text = '$\dot{m}_{in}$';
            app.dotm_inEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dotm_inEditField.ValueChangedFcn = createCallbackFcn(app, @dotm_inEditFieldValueChanged, true);
            app.TurbineBladeLengthEditField_3Label = uilabel(app.GridLayout2);
            app.TurbineBladeLengthEditField_3Label.Text = 'Turbine Blade Length';
            app.TurbineBladeLengthEditField_3 = uieditfield(app.GridLayout2, 'numeric');
            app.TurbineBladeLengthEditField_3.ValueChangedFcn = createCallbackFcn(app, @TurbineBladeLengthEditField_3ValueChanged, true);
            app.DiffuserLengthEditField_2Label = uilabel(app.GridLayout2);
            app.DiffuserLengthEditField_2Label.Text = 'Diffuser Length';
            app.DiffuserLengthEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.DiffuserLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @DiffuserLengthEditField_2ValueChanged, true);
            app.StraightSectionLengthEditField_2Label = uilabel(app.GridLayout2);
            app.StraightSectionLengthEditField_2Label.Text = 'Straight Section Length';
            app.StraightSectionLengthEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.StraightSectionLengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @StraightSectionLengthEditField_2ValueChanged, true);
            app.FlameHolderHalfAngleEditField_2Label = uilabel(app.GridLayout2);
            app.FlameHolderHalfAngleEditField_2Label.Text = 'Flame Holder Half Angle';
            app.FlameHolderHalfAngleEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.FlameHolderHalfAngleEditField_2.ValueChangedFcn = createCallbackFcn(app, @FlameHolderHalfAngleEditField_2ValueChanged, true);
            app.FlameHolderHeightEditField_2Label = uilabel(app.GridLayout2);
            app.FlameHolderHeightEditField_2Label.Text = 'Flame Holder Height';
            app.FlameHolderHeightEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.FlameHolderHeightEditField_2.ValueChangedFcn = createCallbackFcn(app, @FlameHolderHeightEditField_2ValueChanged, true);
            app.NozzleDiameterEditFieldLabel = uilabel(app.GridLayout2);
            app.NozzleDiameterEditFieldLabel.Text = 'Nozzle Diameter';
            app.NozzleDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NozzleDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @NozzleDiameterEditFieldValueChanged, true);
            app.TurbineDiameterEditFieldLabel = uilabel(app.GridLayout2);
            app.TurbineDiameterEditFieldLabel.Text = 'Turbine Diameter';
            app.TurbineDiameterEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TurbineDiameterEditField.ValueChangedFcn = createCallbackFcn(app, @TurbineDiameterEditFieldValueChanged, true);
            app.dotm_fuelEditFieldLabel = uilabel(app.GridLayout2);
            app.dotm_fuelEditFieldLabel.Interpreter = 'latex';
            app.dotm_fuelEditFieldLabel.Text = '$\dot{m}_{fuel}$';
            app.dotm_fuelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dotm_fuelEditField.ValueChangedFcn = createCallbackFcn(app, @dotm_fuelEditFieldValueChanged, true);
            app.NumberofPlotPointsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofPlotPointsEditFieldLabel.Text = 'Number of Plot Points';
            app.NumberofPlotPointsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofPlotPointsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofPlotPointsEditFieldValueChanged, true);
            app.JkgKLabel = uilabel(app.GridLayout2);
            app.JkgKLabel.Text = 'J/kg-K';
            app.kgm3Label = uilabel(app.GridLayout2);
            app.kgm3Label.Text = 'kg/m^3';
            app.CLabel = uilabel(app.GridLayout2);
            app.CLabel.Text = 'C';
            app.kmhrLabel = uilabel(app.GridLayout2);
            app.kmhrLabel.Text = 'km/hr';
            app.LsLabel = uilabel(app.GridLayout2);
            app.LsLabel.Text = 'L/s';
            app.mLabel = uilabel(app.GridLayout2);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout2);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.GridLayout2);
            app.mLabel_3.Text = 'm';
            app.degLabel = uilabel(app.GridLayout2);
            app.degLabel.Text = 'deg';
            app.mmLabel_3 = uilabel(app.GridLayout2);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_2 = uilabel(app.GridLayout2);
            app.mmLabel_2.Text = 'mm';
            app.mmLabel = uilabel(app.GridLayout2);
            app.mmLabel.Text = 'mm';
            app.gminLabel = uilabel(app.GridLayout2);
            app.gminLabel.Text = 'g/min';
            app.MachNumberPostDiffuserEditField_2Label = uilabel(app.GridLayout2);
            app.MachNumberPostDiffuserEditField_2Label.Text = 'Mach Number Post Diffuser';
            app.MachNumberPostDiffuserEditField_2 = uieditfield(app.GridLayout2, 'numeric');
            app.MachNumberPostDiffuserEditField_2.ValueChangedFcn = createCallbackFcn(app, @MachNumberPostDiffuserEditField_2ValueChanged, true);
            app.RunButton_2 = uibutton(app.MainCalculationsTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.SaveButton_2 = uibutton(app.MainCalculationsTab, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.SaveFileNameEditField_2Label = uilabel(app.MainCalculationsTab);
            app.SaveFileNameEditField_2Label.Text = 'Save File Name';
            app.SaveFileNameEditField_2 = uieditfield(app.MainCalculationsTab, 'text');
            app.SaveFileNameEditField_2.ValueChangedFcn = createCallbackFcn(app, @SaveFileNameEditField_2ValueChanged, true);
            app.ResetButton = uibutton(app.MainCalculationsTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StopButton = uibutton(app.MainCalculationsTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.CombustionAnalysisTab_2 = uitab(app.TabGroup);
            app.CombustionAnalysisTab_2.Title = 'Combustion Analysis';
            app.Image_5 = uiimage(app.CombustionAnalysisTab_2);
            app.Image_5.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.CombustionParametersPanel = uipanel(app.CombustionAnalysisTab_2);
            app.CombustionParametersPanel.Title = 'Combustion Parameters';
            app.GridLayout6 = uigridlayout(app.CombustionParametersPanel);
            app.CombustionResultsPanel = uipanel(app.CombustionAnalysisTab_2);
            app.CombustionResultsPanel.Title = 'Combustion Results';
            app.GridLayout4 = uigridlayout(app.CombustionResultsPanel);
            app.AfterburnerTemperatureEditFieldLabel = uilabel(app.GridLayout4);
            app.AfterburnerTemperatureEditFieldLabel.Text = 'Afterburner Temperature';
            app.AfterburnerTemperatureEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AfterburnerTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @AfterburnerTemperatureEditFieldValueChanged, true);
            app.AfterburnerPressureEditFieldLabel = uilabel(app.GridLayout4);
            app.AfterburnerPressureEditFieldLabel.Text = 'Afterburner Pressure';
            app.AfterburnerPressureEditField = uieditfield(app.GridLayout4, 'numeric');
            app.AfterburnerPressureEditField.ValueChangedFcn = createCallbackFcn(app, @AfterburnerPressureEditFieldValueChanged, true);
            app.PhiLabel = uilabel(app.GridLayout4);
            app.PhiLabel.Interpreter = 'latex';
            app.PhiLabel.Text = '$\Phi$';
            app.PhiEditField = uieditfield(app.GridLayout4, 'numeric');
            app.PhiEditField.ValueChangedFcn = createCallbackFcn(app, @PhiEditFieldValueChanged, true);
            app.f_actualLabel = uilabel(app.GridLayout4);
            app.f_actualLabel.Interpreter = 'latex';
            app.f_actualLabel.Text = '$f_{actual}$';
            app.f_actualEditField = uieditfield(app.GridLayout4, 'numeric');
            app.f_actualEditField.ValueChangedFcn = createCallbackFcn(app, @f_actualEditFieldValueChanged, true);
            app.f_stoichiometricLabel = uilabel(app.GridLayout4);
            app.f_stoichiometricLabel.Interpreter = 'latex';
            app.f_stoichiometricLabel.Text = '$f_{stoichiometric}$';
            app.f_stoichiometricEditField = uieditfield(app.GridLayout4, 'numeric');
            app.f_stoichiometricEditField.ValueChangedFcn = createCallbackFcn(app, @f_stoichiometricEditFieldValueChanged, true);
            app.KLabel = uilabel(app.GridLayout4);
            app.KLabel.Text = 'K';
            app.kPaLabel = uilabel(app.GridLayout4);
            app.kPaLabel.Text = 'kPa';
            app.EvaluateButton = uibutton(app.CombustionAnalysisTab_2, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @EvaluateButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.FinalResultsTab = uitab(app.TabGroup);
            app.FinalResultsTab.Title = 'Final Results';
            app.UIAxes2 = uiaxes(app.FinalResultsTab);
            title(app.UIAxes2, 'Afterburner Geometry')
            xlabel(app.UIAxes2, 'Axial Distance (m)')
            ylabel(app.UIAxes2, 'Vertical Distance (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.FinalResultsTab);
            title(app.UIAxes3, 'Flow Parameters')
            xlabel(app.UIAxes3, 'Axial Distance')
            ylabel(app.UIAxes3, 'Vertical Distance')
            zlabel(app.UIAxes3, 'Z')
            app.Image_3 = uiimage(app.FinalResultsTab);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.UnitConversionsTab = uitab(app.TabGroup);
            app.UnitConversionsTab.Title = 'Unit Conversions';
            app.UnitConversionsTab.ButtonDownFcn = createCallbackFcn(app, @UnitConversionsTabButtonDown, true);
            app.GridLayout7 = uigridlayout(app.UnitConversionsTab);
            app.TemperatureDropDown = uidropdown(app.GridLayout7);
            app.TemperatureDropDown.Items = {'Kelvin -> Celsius', 'Kelvin -> Fahrenheit', 'Celsius -> Kelvin', 'Celsius -> Fahrenheit', 'Fahrenheit -> Kelvin', 'Fahrenheit -> Celsius'};
            app.TemperatureDropDown.ValueChangedFcn = createCallbackFcn(app, @TemperatureDropDownValueChanged, true);
            app.TemperatureDropDown.Value = 'Kelvin -> Celsius';
            app.InputTemperatureEditFieldLabel = uilabel(app.GridLayout7);
            app.InputTemperatureEditFieldLabel.Text = 'Input Temperature';
            app.InputTemperatureEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputTemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @InputTemperatureEditFieldValueChanged, true);
            app.OutputTemperatureEditFieldLabel = uilabel(app.GridLayout7);
            app.OutputTemperatureEditFieldLabel.Text = 'Output Temperature';
            app.OutputTemperatureEditField = uieditfield(app.GridLayout7, 'numeric');
            app.PressureDropDown = uidropdown(app.GridLayout7);
            app.PressureDropDown.Items = {'Pa -> kPA', 'kPa -> Pa', 'kPa -> bars', 'kPa -> atm', 'atm -> kPa', 'atm -> bars', 'bars -> kPa', 'bars -> atm'};
            app.PressureDropDown.ValueChangedFcn = createCallbackFcn(app, @PressureDropDownValueChanged, true);
            app.PressureDropDown.Value = 'Pa -> kPA';
            app.VelocityDropDown = uidropdown(app.GridLayout7);
            app.VelocityDropDown.Items = {'m/s -> km/hr', 'm/s -> mph', 'km/hr -> m/s', 'km/hr -> mph', 'mph -> m/s', 'mph -> km/hr'};
            app.VelocityDropDown.ValueChangedFcn = createCallbackFcn(app, @VelocityDropDownValueChanged, true);
            app.VelocityDropDown.Value = 'm/s -> mph';
            app.FlowRateDropDown = uidropdown(app.GridLayout7);
            app.FlowRateDropDown.Items = {'kg/s -> kg/min', 'kg/s -> g/min', 'kg/min -> kg/s', 'kg/min -> g/min', 'g/min -> kg/s', 'g/min -> kg/min', 'm^3/s -> m^3/min', 'm^3/s -> L/s', 'm^3/min -> m^3/s', 'm^3/min -> L/s', 'L/s -> m^3/s', 'L/s -> m^3/min'};
            app.FlowRateDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowRateDropDownValueChanged, true);
            app.FlowRateDropDown.Value = 'kg/s -> kg/min';
            app.UnitConversionToolboxLabel = uilabel(app.GridLayout7);
            app.UnitConversionToolboxLabel.Text = 'Unit Conversion Toolbox';
            app.InputPressureEditFieldLabel = uilabel(app.GridLayout7);
            app.InputPressureEditFieldLabel.Text = 'Input Pressure';
            app.InputPressureEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputPressureEditField.ValueChangedFcn = createCallbackFcn(app, @InputPressureEditFieldValueChanged, true);
            app.OutputPressureEditFieldLabel = uilabel(app.GridLayout7);
            app.OutputPressureEditFieldLabel.Text = 'Output Pressure';
            app.OutputPressureEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputVelocityEditFieldLabel = uilabel(app.GridLayout7);
            app.InputVelocityEditFieldLabel.Text = 'Input Velocity';
            app.InputVelocityEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputVelocityEditField.ValueChangedFcn = createCallbackFcn(app, @InputVelocityEditFieldValueChanged, true);
            app.OutputVelocityEditFieldLabel = uilabel(app.GridLayout7);
            app.OutputVelocityEditFieldLabel.Text = 'Output Velocity';
            app.OutputVelocityEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputFlowRateEditFieldLabel = uilabel(app.GridLayout7);
            app.InputFlowRateEditFieldLabel.Text = 'Input Flow Rate';
            app.InputFlowRateEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputFlowRateEditField.ValueChangedFcn = createCallbackFcn(app, @InputFlowRateEditFieldValueChanged, true);
            app.OutputFlowRateEditFieldLabel = uilabel(app.GridLayout7);
            app.OutputFlowRateEditFieldLabel.Text = 'Output Flow Rate';
            app.OutputFlowRateEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CalculateTempButton = uibutton(app.GridLayout7, 'push');
            app.CalculateTempButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateTempButtonPushed, true);
            app.CalculateTempButton.Text = 'Calculate Temp';
            app.CalculatePressureButton = uibutton(app.GridLayout7, 'push');
            app.CalculatePressureButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePressureButtonPushed, true);
            app.CalculatePressureButton.Text = 'Calculate Pressure';
            app.CalculateVelocityButton = uibutton(app.GridLayout7, 'push');
            app.CalculateVelocityButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVelocityButtonPushed, true);
            app.CalculateVelocityButton.Text = 'Calculate Velocity';
            app.CalculateFlowRateButton = uibutton(app.GridLayout7, 'push');
            app.CalculateFlowRateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFlowRateButtonPushed, true);
            app.CalculateFlowRateButton.Text = 'Calculate Flow Rate';
            app.InputFlowRateEditField_2Label = uilabel(app.GridLayout7);
            app.InputFlowRateEditField_2Label.Text = 'Input Flow Rate';
            app.InputFlowSwapEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputFlowSwapEditField.ValueChangedFcn = createCallbackFcn(app, @InputFlowSwapEditFieldValueChanged, true);
            app.InputDensitykgm3EditFieldLabel = uilabel(app.GridLayout7);
            app.InputDensitykgm3EditFieldLabel.Text = 'Input Density (kg/m^3)';
            app.InputDensitykgm3EditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputDensitykgm3EditField.ValueChangedFcn = createCallbackFcn(app, @InputDensitykgm3EditFieldValueChanged, true);
            app.TemperatureLabel = uilabel(app.GridLayout7);
            app.TemperatureLabel.Text = 'Temperature:';
            app.PressureLabel = uilabel(app.GridLayout7);
            app.PressureLabel.Text = 'Pressure:';
            app.VelocityLabel = uilabel(app.GridLayout7);
            app.VelocityLabel.Text = 'Velocity:';
            app.MassorVolumeFRLabel = uilabel(app.GridLayout7);
            app.MassorVolumeFRLabel.Text = 'Mass or Volume FR:';
            app.OutputFlowRateEditField_2Label = uilabel(app.GridLayout7);
            app.OutputFlowRateEditField_2Label.Text = 'Output Flow Rate';
            app.OutputFlowSwapEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CalculateFlowSwapButton = uibutton(app.GridLayout7, 'push');
            app.CalculateFlowSwapButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateFlowSwapButtonPushed, true);
            app.CalculateFlowSwapButton.Text = 'Calculate Flow Swap';
            app.FlowSwapDropDown = uidropdown(app.GridLayout7);
            app.FlowSwapDropDown.Items = {'kg/s -> m^3/s', 'm^3/s -> kg/s'};
            app.FlowSwapDropDown.ValueChangedFcn = createCallbackFcn(app, @FlowSwapDropDownValueChanged, true);
            app.FlowSwapDropDown.Value = 'kg/s -> m^3/s';
            app.MasstoVolumeFRLabel = uilabel(app.GridLayout7);
            app.MasstoVolumeFRLabel.Text = 'Mass to Volume FR:';
            app.VolumeDropDown = uidropdown(app.GridLayout7);
            app.VolumeDropDown.Items = {'m^3 -> L', 'm^3 -> cm^3', 'cm^3 -> L', 'cm^3 -> m^3', 'L -> m^3', 'L -> cm^3'};
            app.VolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @VolumeDropDownValueChanged, true);
            app.VolumeDropDown.Value = 'm^3 -> L';
            app.VolumeLabel = uilabel(app.GridLayout7);
            app.VolumeLabel.Text = 'Volume:';
            app.InputVolumeEditFieldLabel = uilabel(app.GridLayout7);
            app.InputVolumeEditFieldLabel.Text = 'Input Volume';
            app.InputVolumeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.InputVolumeEditField.ValueChangedFcn = createCallbackFcn(app, @InputVolumeEditFieldValueChanged, true);
            app.OutputVolumeEditFieldLabel = uilabel(app.GridLayout7);
            app.OutputVolumeEditFieldLabel.Text = 'Output Volume';
            app.OutputVolumeEditField = uieditfield(app.GridLayout7, 'numeric');
            app.CalculateVolumeButton = uibutton(app.GridLayout7, 'push');
            app.CalculateVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateVolumeButtonPushed, true);
            app.CalculateVolumeButton.Text = 'Calculate Volume';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.Image_7 = uiimage(app.SettingsTab);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'MW', 'MW_Grad_8Kfit (1).png');
            app.ThemeDropDownLabel = uilabel(app.SettingsTab);
            app.ThemeDropDownLabel.Text = 'Theme';
            app.ThemeDropDown = uidropdown(app.SettingsTab);
            app.ThemeDropDown.Items = {'Light Mode', 'Dark Mode'};
            app.ThemeDropDown.ValueChangedFcn = createCallbackFcn(app, @ThemeDropDownValueChanged, true);
            app.ThemeDropDown.Value = 'Light Mode';
            app.PlotColorPanel = uipanel(app.SettingsTab);
            app.PlotColorPanel.Title = 'Plot Color';
            app.GridLayout5 = uigridlayout(app.PlotColorPanel);
            app.EngineDropDownLabel = uilabel(app.GridLayout5);
            app.EngineDropDownLabel.Text = 'Engine';
            app.EngineDropDown = uidropdown(app.GridLayout5);
            app.EngineDropDown.Items = {'Black', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Red', 'White'};
            app.EngineDropDown.ValueChangedFcn = createCallbackFcn(app, @EngineDropDownValueChanged, true);
            app.EngineDropDown.Value = 'Red';
            app.DiffuserDropDownLabel = uilabel(app.GridLayout5);
            app.DiffuserDropDownLabel.Text = 'Diffuser ';
            app.DiffuserDropDown = uidropdown(app.GridLayout5);
            app.DiffuserDropDown.Items = {'Red', 'Green', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.DiffuserDropDown.Value = 'Red';
            app.StraightSectionDropDownLabel = uilabel(app.GridLayout5);
            app.StraightSectionDropDownLabel.Text = 'Straight Section';
            app.StraightSectionDropDown = uidropdown(app.GridLayout5);
            app.StraightSectionDropDown.Items = {'Magenta', 'Green', 'Blue', 'Cyan', 'Red', 'Yellow', 'Black', 'White'};
            app.StraightSectionDropDown.Value = 'Red';
            app.CombustionChamberDropDownLabel = uilabel(app.GridLayout5);
            app.CombustionChamberDropDownLabel.Text = 'Combustion Chamber';
            app.CombustionChamberDropDown = uidropdown(app.GridLayout5);
            app.CombustionChamberDropDown.Items = {'Blue', 'Green', 'Red', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.CombustionChamberDropDown.Value = 'Red';
            app.FlameHolderDropDownLabel = uilabel(app.GridLayout5);
            app.FlameHolderDropDownLabel.Text = 'Flame Holder';
            app.FlameHolderDropDown = uidropdown(app.GridLayout5);
            app.FlameHolderDropDown.Items = {'Green', 'Red', 'Blue', 'Cyan', 'Magenta', 'Yellow', 'Black', 'White'};
            app.FlameHolderDropDown.Value = 'Red';
            app.NozzleDropDownLabel = uilabel(app.GridLayout5);
            app.NozzleDropDownLabel.Text = 'Nozzle';
            app.NozzleDropDown = uidropdown(app.GridLayout5);
            app.NozzleDropDown.Items = {'Cyan', 'Green', 'Blue', 'Red', 'Magenta', 'Yellow', 'Black', 'White'};
            app.NozzleDropDown.Value = 'Red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ignition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end